classdef Steganography_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExitButton                  matlab.ui.control.Button
        SaveTheEmbeddedImageButton  matlab.ui.control.Button
        ExtractButton               matlab.ui.control.Button
        EmbedButton                 matlab.ui.control.Button
        BrowseSecretImageButton     matlab.ui.control.Button
        BrowseCoverImageButton      matlab.ui.control.Button
        headline                    matlab.ui.control.TextArea
        Extracted_Image             matlab.ui.control.UIAxes
        Embedded_Image              matlab.ui.control.UIAxes
        Secret_Image                matlab.ui.control.UIAxes
        Cover_Image                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        cover_im;
        secret_im;
        wavelet_func = 'db1';
        a = 0.04; % Assume a - the embedding co-efficient of value ranging from 0 to 1, large increase in robustness, small increase in transparency.
        embedded_im_double;
        embedded_im;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseCoverImageButton
        function BrowseCoverImageButtonPushed(app, event)
            [name, path, ~] = uigetfile('*.jpg;*jpeg;*.png;*.tiff;*.gif');
            app.cover_im = imread(strcat(path, name));
            imshow(app.cover_im,'Parent',app.Cover_Image);
        end

        % Button pushed function: BrowseSecretImageButton
        function BrowseSecretImageButtonPushed(app, event)
            [name, path, ~] = uigetfile('*.jpg;*jpeg;*.png;*.tiff;*.gif');
            app.secret_im = imread(strcat(path, name));
            imshow(app.secret_im,'Parent',app.Secret_Image);
        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            %Match the size of the images.
            [row_cover,col_cover,~] = size(app.cover_im);
            [row_secret,col_secret,~] = size(app.secret_im);
            if row_cover > row_secret
             app.secret_im = padarray(app.secret_im,(row_cover-row_secret),0, 'post');
            elseif row_secret > row_cover
              app.cover_im  = padarray(app.cover_im ,(row_secret-row_cover),0, 'post');
            end

            if col_cover > col_secret
              app.secret_im = padarray(app.secret_im,[0 (col_cover-col_secret)],0, 'post');
            elseif col_secret > col_cover
                app.cover_im = padarray(app.cover_im,[0 (col_secret-col_cover)],0, 'post');
            end

            % Split the cover and secret images to RGB channels.
            [R_ch_cover , G_ch_cover , B_ch_cover] = imsplit(app.cover_im);
            [R_ch_secret , G_ch_secret , B_ch_secret] = imsplit(app.secret_im);

            % Performing single level 2D DWT decomposition for each channel of the cover and secret images.
            [cA_R_cover,cH_R_cover,cV_R_cover,cD_R_cover] = dwt2(R_ch_cover,app.wavelet_func);
            [cA_G_cover,cH_G_cover,cV_G_cover,cD_G_cover] = dwt2(G_ch_cover,app.wavelet_func);
            [cA_B_cover,cH_B_cover,cV_B_cover,cD_B_cover] = dwt2(B_ch_cover,app.wavelet_func);
            [cA_R_secret,cH_R_secret,cV_R_secret,cD_R_secret] = dwt2(R_ch_secret,app.wavelet_func);
            [cA_G_secret,cH_G_secret,cV_G_secret,cD_G_secret] = dwt2(G_ch_secret,app.wavelet_func);
            [cA_B_secret,cH_B_secret,cV_B_secret,cD_B_secret] = dwt2(B_ch_secret,app.wavelet_func);
            
            % Finding the co-efficient of the embedded image
            cA_R_embedded = (1-app.a)*cA_R_cover + app.a*cA_R_secret;
            cH_R_embedded = (1-app.a)*cH_R_cover + app.a*cH_R_secret;
            cV_R_embedded = (1-app.a)*cV_R_cover + app.a*cV_R_secret;
            cD_R_embedded = (1-app.a)*cD_R_cover + app.a*cD_R_secret;

            cA_G_embedded = (1-app.a)*cA_G_cover + app.a*cA_G_secret;
            cH_G_embedded = (1-app.a)*cH_G_cover + app.a*cH_G_secret;
            cV_G_embedded = (1-app.a)*cV_G_cover + app.a*cV_G_secret;
            cD_G_embedded = (1-app.a)*cD_G_cover + app.a*cD_G_secret;

            cA_B_embedded = (1-app.a)*cA_B_cover + app.a*cA_B_secret;
            cH_B_embedded = (1-app.a)*cH_B_cover + app.a*cH_B_secret;
            cV_B_embedded = (1-app.a)*cV_B_cover + app.a*cV_B_secret;
            cD_B_embedded = (1-app.a)*cD_B_cover + app.a*cD_B_secret;

            % Perform single level 2D- Daubechies inverse DWT decomposition
            R_ch_embedded_double = idwt2(cA_R_embedded,cH_R_embedded,cV_R_embedded,cD_R_embedded,app.wavelet_func);
            G_ch_embedded_double = idwt2(cA_G_embedded,cH_G_embedded,cV_G_embedded,cD_G_embedded,app.wavelet_func);
            B_ch_embedded_double = idwt2(cA_B_embedded,cH_B_embedded,cV_B_embedded,cD_B_embedded,app.wavelet_func);

            R_ch_embedded = uint8(R_ch_embedded_double); 
            G_ch_embedded = uint8(G_ch_embedded_double);
            B_ch_embedded = uint8(B_ch_embedded_double);

            %Concatenate Red (R), Green (G) plane and Blue (B) plane to get the embedded image
            app.embedded_im = cat(3,R_ch_embedded,G_ch_embedded,B_ch_embedded);
            app.embedded_im_double = cat(3,R_ch_embedded_double,G_ch_embedded_double,B_ch_embedded_double);

            % Detecting the corner of the embedded image for cropping it in case that the secret image is bigger that the cover image.
%             if row_secret > row_cover || col_secret > col_cover
%                 app.embedded_im = padarray(app.embedded_im,[50 50],0, 'post');
%                 t = rgb2gray(app.embedded_im);
%                 corners = detectHarrisFeatures(t);
%                 c =ceil(corners.Location(end,end-1));
%                 r =ceil(corners.Location(end,end));
%                 app.embedded_im = app.embedded_im(1:r,1:c,:);
%             end

            imshow(app.embedded_im,'Parent',app.Embedded_Image);
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            embedded_im_ex =app.embedded_im_double;
            % Split the cover and embedded images to RGB channels.
            [R_ch_cover_ex , G_ch_cover_ex , B_ch_cover_ex] = imsplit(app.cover_im);
            [R_ch_embedded_ex , G_ch_embedded_ex , B_ch_embedded_ex] = imsplit(embedded_im_ex);

            % Performing single level 2D DWT decomposition for each channel of the cover and embedded images.
            [cA_R_cover_ex,cH_R_cover_ex,cV_R_cover_ex,cD_R_cover_ex] = dwt2(R_ch_cover_ex,app.wavelet_func);
            [cA_G_cover_ex,cH_G_cover_ex,cV_G_cover_ex,cD_G_cover_ex] = dwt2(G_ch_cover_ex,app.wavelet_func);
            [cA_B_cover_ex,cH_B_cover_ex,cV_B_cover_ex,cD_B_cover_ex] = dwt2(B_ch_cover_ex,app.wavelet_func);

            [cA_R_embedded_ex,cH_R_embedded_ex,cV_R_embedded_ex,cD_R_embedded_ex] = dwt2(R_ch_embedded_ex,app.wavelet_func);
            [cA_G_embedded_ex,cH_G_embedded_ex,cV_G_embedded_ex,cD_G_embedded_ex] = dwt2(G_ch_embedded_ex,app.wavelet_func);
            [cA_B_embedded_ex,cH_B_embedded_ex,cV_B_embedded_ex,cD_B_embedded_ex] = dwt2(B_ch_embedded_ex,app.wavelet_func);

            %  Match the size of the matrixes.
            [row_cover_ex,col_cover_ex] = size(cA_R_cover_ex);
            [row_embedded_ex,col_embedded_ex] = size(cA_R_embedded_ex);
            if row_cover_ex > row_embedded_ex
                cA_R_embedded_ex = padarray(cA_R_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cH_R_embedded_ex = padarray(cH_R_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cV_R_embedded_ex = padarray(cV_R_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cD_R_embedded_ex = padarray(cD_R_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cA_G_embedded_ex = padarray(cA_G_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cH_G_embedded_ex = padarray(cH_G_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cV_G_embedded_ex = padarray(cV_G_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cD_G_embedded_ex = padarray(cD_G_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cA_B_embedded_ex = padarray(cA_B_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cH_B_embedded_ex = padarray(cH_B_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cV_B_embedded_ex = padarray(cV_B_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
                cD_B_embedded_ex = padarray(cD_B_embedded_ex,(row_cover_ex-row_embedded_ex),0, 'post');
            elseif row_embedded_ex > row_cover_ex
                cA_R_cover_ex = padarray(cA_R_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cH_R_cover_ex = padarray(cH_R_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cV_R_cover_ex = padarray(cV_R_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cD_R_cover_ex = padarray(cD_R_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cA_G_cover_ex = padarray(cA_G_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cH_G_cover_ex = padarray(cH_G_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cV_G_cover_ex = padarray(cV_G_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cD_G_cover_ex = padarray(cD_G_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cA_B_cover_ex = padarray(cA_B_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cH_B_cover_ex = padarray(cH_B_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cV_B_cover_ex = padarray(cV_B_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
                cD_B_cover_ex = padarray(cD_B_cover_ex,(row_embedded_ex-row_cover_ex),0, 'post');
            end
            
            if col_cover_ex > col_embedded_ex
                cA_R_embedded_ex = padarray(cA_R_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cH_R_embedded_ex = padarray(cH_R_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cV_R_embedded_ex = padarray(cV_R_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cD_R_embedded_ex = padarray(cD_R_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cA_G_embedded_ex = padarray(cA_G_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cH_G_embedded_ex = padarray(cH_G_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cV_G_embedded_ex = padarray(cV_G_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cD_G_embedded_ex = padarray(cD_G_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cA_B_embedded_ex = padarray(cA_B_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cH_B_embedded_ex = padarray(cH_B_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cV_B_embedded_ex = padarray(cV_B_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
                cD_B_embedded_ex = padarray(cD_B_embedded_ex,[0 (col_cover_ex-col_embedded_ex)],0, 'post');
            elseif col_embedded_ex > col_cover_ex
                cA_R_cover_ex = padarray(cA_R_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cH_R_cover_ex = padarray(cH_R_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cV_R_cover_ex = padarray(cV_R_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cD_R_cover_ex = padarray(cD_R_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cA_G_cover_ex = padarray(cA_G_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cH_G_cover_ex = padarray(cH_G_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cV_G_cover_ex = padarray(cV_G_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cD_G_cover_ex = padarray(cD_G_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cA_B_cover_ex = padarray(cA_B_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cH_B_cover_ex = padarray(cH_B_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cV_B_cover_ex = padarray(cV_B_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
                cD_B_cover_ex = padarray(cD_B_cover_ex,[0 (col_embedded_ex-col_cover_ex)],0, 'post');
            end

            % Finding the co-efficient of the stego image
            cA_R_secret_ex = (cA_R_embedded_ex-(1-app.a)*cA_R_cover_ex)/app.a;
            cH_R_secret_ex = (cH_R_embedded_ex-(1-app.a)*cH_R_cover_ex)/app.a;
            cV_R_secret_ex = (cV_R_embedded_ex-(1-app.a)*cV_R_cover_ex)/app.a;
            cD_R_secret_ex = (cD_R_embedded_ex-(1-app.a)*cD_R_cover_ex)/app.a;
            
            cA_G_secret_ex = (cA_G_embedded_ex-(1-app.a)*cA_G_cover_ex)/app.a;
            cH_G_secret_ex = (cH_G_embedded_ex-(1-app.a)*cH_G_cover_ex)/app.a;
            cV_G_secret_ex = (cV_G_embedded_ex-(1-app.a)*cV_G_cover_ex)/app.a;
            cD_G_secret_ex = (cD_G_embedded_ex-(1-app.a)*cD_G_cover_ex)/app.a;
            
            cA_B_secret_ex = (cA_B_embedded_ex-(1-app.a)*cA_B_cover_ex)/app.a;
            cH_B_secret_ex = (cH_B_embedded_ex-(1-app.a)*cH_B_cover_ex)/app.a;
            cV_B_secret_ex = (cV_B_embedded_ex-(1-app.a)*cV_B_cover_ex)/app.a;
            cD_B_secret_ex = (cD_B_embedded_ex-(1-app.a)*cD_B_cover_ex)/app.a;

            % Perform single level 2D- Daubechies inverse DWT decomposition
            R_ch_secret_ex = idwt2(cA_R_secret_ex,cH_R_secret_ex,cV_R_secret_ex,cD_R_secret_ex,app.wavelet_func);
            G_ch_secret_ex = idwt2(cA_G_secret_ex,cH_G_secret_ex,cV_G_secret_ex,cD_G_secret_ex,app.wavelet_func);
            B_ch_secret_ex = idwt2(cA_B_secret_ex,cH_B_secret_ex,cV_B_secret_ex,cD_B_secret_ex,app.wavelet_func);
            
            R_ch_secret_ex = uint8(R_ch_secret_ex);
            G_ch_secret_ex = uint8(G_ch_secret_ex);
            B_ch_secret_ex = uint8(B_ch_secret_ex);

            %Concatenate Red (R), Green (G) plane and Blue (B) plane to get the embedded image
            secret_im_ex = cat(3,R_ch_secret_ex,G_ch_secret_ex,B_ch_secret_ex);
            
            % Detecting the corner of the secret image for cropping it.
                secret_im_ex = padarray(secret_im_ex,[50 50],0, 'post');
                t = rgb2gray(secret_im_ex);
                corners = detectHarrisFeatures(t);
                c =ceil(corners.Location(end,end-1));
                r =ceil(corners.Location(end,end));
                secret_im_ex = secret_im_ex(1:r,1:c,:);

            imshow(secret_im_ex,'Parent',app.Extracted_Image);
        end

        % Button pushed function: SaveTheEmbeddedImageButton
        function SaveTheEmbeddedImageButtonPushed(app, event)
            startingFolder = userpath;
            defaultFileName = fullfile(startingFolder, '*.tif');
            [baseFileName, folder] = uiputfile(defaultFileName);
            if baseFileName ~= 0
	            fullFileName = fullfile(folder, baseFileName);
                imwrite(app.embedded_im, fullFileName);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Cover_Image = uiaxes(app.UIFigure);
            title(app.Cover_Image, 'Cover Image')
            zlabel(app.Cover_Image, 'Z')
            app.Cover_Image.XTick = [];
            app.Cover_Image.YTick = [];
            app.Cover_Image.ZTick = [];
            app.Secret_Image = uiaxes(app.UIFigure);
            title(app.Secret_Image, 'Secret  Image')
            zlabel(app.Secret_Image, 'Z')
            app.Secret_Image.XTick = [];
            app.Secret_Image.YTick = [];
            app.Embedded_Image = uiaxes(app.UIFigure);
            title(app.Embedded_Image, 'Embedded Image')
            zlabel(app.Embedded_Image, 'Z')
            app.Embedded_Image.XTick = [];
            app.Embedded_Image.YTick = [];
            app.Extracted_Image = uiaxes(app.UIFigure);
            title(app.Extracted_Image, 'Extracted Image')
            zlabel(app.Extracted_Image, 'Z')
            app.Extracted_Image.XTick = [];
            app.Extracted_Image.YTick = [];
            app.headline = uitextarea(app.UIFigure);
            app.headline.Value = {'Discrete Wavelet Transform based Steganography'};
            app.BrowseCoverImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseCoverImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCoverImageButtonPushed, true);
            app.BrowseCoverImageButton.Text = 'Browse Cover Image';
            app.BrowseSecretImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseSecretImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSecretImageButtonPushed, true);
            app.BrowseSecretImageButton.Text = 'Browse Secret Image';
            app.EmbedButton = uibutton(app.UIFigure, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Text = 'Embed';
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.SaveTheEmbeddedImageButton = uibutton(app.UIFigure, 'push');
            app.SaveTheEmbeddedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTheEmbeddedImageButtonPushed, true);
            app.SaveTheEmbeddedImageButton.Text = {'Save The Embedded'; 'Image'};
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Steganography_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end